classdef TransmissionLinesCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CoefficientsTab                 matlab.ui.container.Tab
        TransmissionCoefficientPanel    matlab.ui.container.Panel
        ResistanceREditFieldLabel       matlab.ui.control.Label
        ResistanceREditField            matlab.ui.control.NumericEditField
        InductanceLEditFieldLabel       matlab.ui.control.Label
        InductanceLEditField            matlab.ui.control.NumericEditField
        CapacitanceCEditFieldLabel      matlab.ui.control.Label
        CapacitanceCEditField           matlab.ui.control.NumericEditField
        angular_velocitywEditFieldLabel  matlab.ui.control.Label
        angular_velocitywEditField      matlab.ui.control.NumericEditField
        ConductanceGEditFieldLabel      matlab.ui.control.Label
        ConductanceGEditField           matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        Transmission_coeffEditField_2Label  matlab.ui.control.Label
        Transmission_coeffEditField_2   matlab.ui.control.EditField
        ReflectionCoefficientPanel      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        VrViTab                         matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        CalculateButton_9               matlab.ui.control.Button
        ReflectedvoltageVrEditField_3Label  matlab.ui.control.Label
        ReflectedvoltageVrEditField_3   matlab.ui.control.NumericEditField
        IncidentvoltageViEditField_3Label  matlab.ui.control.Label
        IncidentvoltageViEditField_3    matlab.ui.control.NumericEditField
        Transmission_coeffEditField_5Label  matlab.ui.control.Label
        Transmission_coeffEditField_5   matlab.ui.control.EditField
        Reflection_coefficientEditField_5Label  matlab.ui.control.Label
        Reflection_coefficientEditField_5  matlab.ui.control.EditField
        DistancexEditField_2Label       matlab.ui.control.Label
        DistancexEditField_2            matlab.ui.control.NumericEditField
        IrIiTab                         matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        CalculateButton_10              matlab.ui.control.Button
        ReflectedCurrentIrEditFieldLabel  matlab.ui.control.Label
        ReflectedCurrentIrEditField     matlab.ui.control.NumericEditField
        IncidentCurrentIiEditFieldLabel  matlab.ui.control.Label
        IncidentCurrentIiEditField      matlab.ui.control.NumericEditField
        Transmission_coeffEditField_6Label  matlab.ui.control.Label
        Transmission_coeffEditField_6   matlab.ui.control.EditField
        Reflection_coefficientEditField_6Label  matlab.ui.control.Label
        Reflection_coefficientEditField_6  matlab.ui.control.EditField
        DistancexEditField_3Label       matlab.ui.control.Label
        DistancexEditField_3            matlab.ui.control.NumericEditField
        usingimpedancex0Tab             matlab.ui.container.Tab
        LoadImpedanceZlEditField_3Label  matlab.ui.control.Label
        LoadImpedanceZlEditField_3      matlab.ui.control.EditField
        CharacteristicImpedanceZ0EditField_3Label  matlab.ui.control.Label
        CharacteristicImpedanceZ0EditField_3  matlab.ui.control.EditField
        CalculateButton_7               matlab.ui.control.Button
        Reflection_coefficientEditField_3Label  matlab.ui.control.Label
        Reflection_coefficientEditField_3  matlab.ui.control.EditField
        SelecttheformulayouwanttouseLabel  matlab.ui.control.Label
        VoltageCurrentTab               matlab.ui.container.Tab
        VoltagePanel                    matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        CalculateButton_8               matlab.ui.control.Button
        ReflectedvoltageVrEditField_2Label  matlab.ui.control.Label
        ReflectedvoltageVrEditField_2   matlab.ui.control.NumericEditField
        IncidentvoltageViEditField_2Label  matlab.ui.control.Label
        IncidentvoltageViEditField_2    matlab.ui.control.NumericEditField
        Transmission_coeffEditField_4Label  matlab.ui.control.Label
        Transmission_coeffEditField_4   matlab.ui.control.EditField
        VoltageVEditFieldLabel          matlab.ui.control.Label
        VoltageVEditField               matlab.ui.control.EditField
        DistancexEditFieldLabel         matlab.ui.control.Label
        DistancexEditField              matlab.ui.control.NumericEditField
        CurrentPanel                    matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        CalculateButton_11              matlab.ui.control.Button
        ReflectedcurrentIrLabel         matlab.ui.control.Label
        ReflectedcurrentIrEditField     matlab.ui.control.NumericEditField
        IncidentcurrentIiLabel          matlab.ui.control.Label
        IncidentcurrentIiEditField      matlab.ui.control.NumericEditField
        Transmission_coeffEditField_7Label  matlab.ui.control.Label
        Transmission_coeffEditField_7   matlab.ui.control.EditField
        CurrentIEditFieldLabel          matlab.ui.control.Label
        CurrentIEditField               matlab.ui.control.EditField
        DistancexEditField_4Label       matlab.ui.control.Label
        DistancexEditField_4            matlab.ui.control.NumericEditField
        ImpedancesTab                   matlab.ui.container.Tab
        CharacteristicImpedancePanel    matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        VITab                           matlab.ui.container.Tab
        VoltageVEditField_2Label        matlab.ui.control.Label
        VoltageVEditField_2             matlab.ui.control.NumericEditField
        CurrentIEditField_2Label        matlab.ui.control.Label
        CurrentIEditField_2             matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ViIiButton                      matlab.ui.control.RadioButton
        VrIrButton                      matlab.ui.control.RadioButton
        ChoosetheformulaLabel           matlab.ui.control.Label
        CalculateButton_12              matlab.ui.control.Button
        CharactersticImpZ0EditField_8Label  matlab.ui.control.Label
        CharactersticImpZ0EditField_8   matlab.ui.control.EditField
        sqrootRjwLGjwCTab               matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        ResistanceREditField_2Label     matlab.ui.control.Label
        ResistanceREditField_2          matlab.ui.control.NumericEditField
        InductanceLEditField_2Label     matlab.ui.control.Label
        InductanceLEditField_2          matlab.ui.control.NumericEditField
        angular_velocitywEditField_2Label  matlab.ui.control.Label
        angular_velocitywEditField_2    matlab.ui.control.NumericEditField
        ConductanceGEditField_2Label    matlab.ui.control.Label
        ConductanceGEditField_2         matlab.ui.control.NumericEditField
        CharactersticImpZ0EditField_2Label  matlab.ui.control.Label
        CharactersticImpZ0EditField_2   matlab.ui.control.EditField
        CapacitanceCEditField_2Label    matlab.ui.control.Label
        CapacitanceCEditField_2         matlab.ui.control.NumericEditField
        CalculateButton_13              matlab.ui.control.Button
        ZL1R1RTab                       matlab.ui.container.Tab
        ReflectionCoefficientREditFieldLabel  matlab.ui.control.Label
        ReflectionCoefficientREditField  matlab.ui.control.NumericEditField
        CalculateButton_14              matlab.ui.control.Button
        CharactersticImpZ0EditField_7Label  matlab.ui.control.Label
        CharactersticImpZ0EditField_7   matlab.ui.control.EditField
        LoadImpedanceZLEditField_3Label  matlab.ui.control.Label
        LoadImpedanceZLEditField_3      matlab.ui.control.EditField
        ChoosetherequiredmethodLabel    matlab.ui.control.Label
        InputImpedancePanel             matlab.ui.container.Panel
        TabGroup3_2                     matlab.ui.container.TabGroup
        VsIsTab                         matlab.ui.container.Tab
        CalculateButton_17              matlab.ui.control.Button
        ViEditFieldLabel                matlab.ui.control.Label
        ViEditField                     matlab.ui.control.NumericEditField
        VrEditFieldLabel                matlab.ui.control.Label
        VrEditField                     matlab.ui.control.NumericEditField
        IiEditFieldLabel                matlab.ui.control.Label
        IiEditField                     matlab.ui.control.NumericEditField
        IrEditFieldLabel                matlab.ui.control.Label
        IrEditField                     matlab.ui.control.NumericEditField
        Z0EditFieldLabel                matlab.ui.control.Label
        Z0EditField                     matlab.ui.control.EditField
        InputImpedancceZinEditFieldLabel  matlab.ui.control.Label
        InputImpedancceZinEditField     matlab.ui.control.EditField
        NoteprovideZovalueofyoudonthavevoltagevaluesLabel  matlab.ui.control.Label
        lEditField_2Label               matlab.ui.control.Label
        lEditField_2                    matlab.ui.control.NumericEditField
        Transmission_coeffEditField_8Label  matlab.ui.control.Label
        Transmission_coeffEditField_8   matlab.ui.control.EditField
        ZoZlZotanhTlZoZLtanhTlTab       matlab.ui.container.Tab
        CalculateButton_19              matlab.ui.control.Button
        ZLEditFieldLabel                matlab.ui.control.Label
        ZLEditField                     matlab.ui.control.EditField
        ZoEditFieldLabel                matlab.ui.control.Label
        ZoEditField                     matlab.ui.control.EditField
        DropDown                        matlab.ui.control.DropDown
        EditField                       matlab.ui.control.EditField
        lEditFieldLabel                 matlab.ui.control.Label
        lEditField                      matlab.ui.control.NumericEditField
        InputImpedanceZinEditField_3Label  matlab.ui.control.Label
        InputImpedanceZinEditField_3    matlab.ui.control.EditField
        ChoosetherequiredmethodLabel_2  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LoadImpedancePanel              matlab.ui.container.Panel
        TabGroup3_3                     matlab.ui.container.TabGroup
        VLILTab                         matlab.ui.container.Tab
        CalculateButton_23              matlab.ui.control.Button
        ViEditField_2Label              matlab.ui.control.Label
        ViEditField_2                   matlab.ui.control.NumericEditField
        VrEditField_2Label              matlab.ui.control.Label
        VrEditField_2                   matlab.ui.control.NumericEditField
        IiEditField_2Label              matlab.ui.control.Label
        IiEditField_2                   matlab.ui.control.NumericEditField
        IrEditField_2Label              matlab.ui.control.Label
        IrEditField_2                   matlab.ui.control.NumericEditField
        Z0EditField_2Label              matlab.ui.control.Label
        Z0EditField_2                   matlab.ui.control.EditField
        LoadImpedancceZLEditFieldLabel  matlab.ui.control.Label
        LoadImpedancceZLEditField       matlab.ui.control.EditField
        NoteprovideZovalueofyoudonthavevoltagevaluesLabel_2  matlab.ui.control.Label
        Zo1R1RTab                       matlab.ui.container.Tab
        ReflectionCoefficientREditField_2Label  matlab.ui.control.Label
        ReflectionCoefficientREditField_2  matlab.ui.control.NumericEditField
        CalculateButton_22              matlab.ui.control.Button
        LoadImpedanceZLEditField_4Label  matlab.ui.control.Label
        LoadImpedanceZLEditField_4      matlab.ui.control.EditField
        CharacteristicImpedanceZoEditFieldLabel  matlab.ui.control.Label
        CharacteristicImpedanceZoEditField  matlab.ui.control.EditField
        StandingWaveRatioTab            matlab.ui.container.Tab
        VmaxxVminxPanel                 matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        CalculateButton_25              matlab.ui.control.Button
        VmaxxEditFieldLabel             matlab.ui.control.Label
        VmaxxEditField                  matlab.ui.control.NumericEditField
        VminxEditFieldLabel             matlab.ui.control.Label
        VminxEditField                  matlab.ui.control.NumericEditField
        StandingWaveRatioSEditFieldLabel  matlab.ui.control.Label
        StandingWaveRatioSEditField     matlab.ui.control.EditField
        R1RPanel                        matlab.ui.container.Panel
        Panel_7                         matlab.ui.container.Panel
        CalculateButton_26              matlab.ui.control.Button
        ReflectionCoefficientREditField_3Label  matlab.ui.control.Label
        ReflectionCoefficientREditField_3  matlab.ui.control.NumericEditField
        StandingWaveRatioSEditField_2Label  matlab.ui.control.Label
        StandingWaveRatioSEditField_2   matlab.ui.control.EditField
        NumericalTab                    matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        StandingWaveRatioSEditField_3Label  matlab.ui.control.Label
        StandingWaveRatioSEditField_3   matlab.ui.control.NumericEditField
        DropDown_3                      matlab.ui.control.DropDown
        EditField_3                     matlab.ui.control.NumericEditField
        DistanceofLabel                 matlab.ui.control.Label
        fromloadLabel                   matlab.ui.control.Label
        WavelengthLabel                 matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        CharacteristicImpedanceZoEditField_2Label  matlab.ui.control.Label
        CharacteristicImpedanceZoEditField_2  matlab.ui.control.EditField
        TransmissionCoeffTEditFieldLabel  matlab.ui.control.Label
        TransmissionCoeffTEditField     matlab.ui.control.EditField
        DistancefromloadxEditFieldLabel  matlab.ui.control.Label
        DistancefromloadxEditField      matlab.ui.control.NumericEditField
        FillinthevaluesLabel            matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        ReflectioncoefficientRx0EditFieldLabel  matlab.ui.control.Label
        ReflectioncoefficientRx0EditField  matlab.ui.control.EditField
        LoadResistanceZLEditFieldLabel  matlab.ui.control.Label
        LoadResistanceZLEditField       matlab.ui.control.EditField
        ReflectioncoefficientRxEditFieldLabel  matlab.ui.control.Label
        ReflectioncoefficientRxEditField  matlab.ui.control.EditField
        ConclusionEditFieldLabel        matlab.ui.control.Label
        ConclusionEditField             matlab.ui.control.EditField
        CalculateButton_27              matlab.ui.control.Button
        PlotTab                         matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        ViorIiEditFieldLabel            matlab.ui.control.Label
        ViorIiEditField                 matlab.ui.control.NumericEditField
        VrorIrEditFieldLabel            matlab.ui.control.Label
        VrorIrEditField                 matlab.ui.control.NumericEditField
        TransmissionCoefficientTEditFieldLabel  matlab.ui.control.Label
        TransmissionCoefficientTEditField  matlab.ui.control.EditField
        PlotButton                      matlab.ui.control.Button
        DistanceofsourcefromloadxEditFieldLabel  matlab.ui.control.Label
        DistanceofsourcefromloadxEditField  matlab.ui.control.NumericEditField
        HistoryTab                      matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        ResistanceREditField_3Label     matlab.ui.control.Label
        ResistanceREditField_3          matlab.ui.control.EditField
        CapacitanceCEditField_3Label    matlab.ui.control.Label
        CapacitanceCEditField_3         matlab.ui.control.EditField
        InductanceLEditField_3Label     matlab.ui.control.Label
        InductanceLEditField_3          matlab.ui.control.EditField
        CharacteristicImpZoEditFieldLabel  matlab.ui.control.Label
        CharacteristicImpZoEditField    matlab.ui.control.EditField
        InputImpedanceZinEditField_4Label  matlab.ui.control.Label
        InputImpedanceZinEditField_4    matlab.ui.control.EditField
        LoadImpedanceZLEditField_5Label  matlab.ui.control.Label
        LoadImpedanceZLEditField_5      matlab.ui.control.EditField
        ConductanceGEditField_3Label    matlab.ui.control.Label
        ConductanceGEditField_3         matlab.ui.control.EditField
        StandingWaveRatioSEditField_4Label  matlab.ui.control.Label
        StandingWaveRatioSEditField_4   matlab.ui.control.EditField
        ReflectionCoefficientREditField_4Label  matlab.ui.control.Label
        ReflectionCoefficientREditField_4  matlab.ui.control.EditField
        VoltageVEditField_3Label        matlab.ui.control.Label
        VoltageVEditField_3             matlab.ui.control.EditField
        CurrentIEditField_3Label        matlab.ui.control.Label
        CurrentIEditField_3             matlab.ui.control.EditField
        TransmissionCoefficientTEditField_2Label  matlab.ui.control.Label
        TransmissionCoefficientTEditField_2  matlab.ui.control.EditField
        AngularvelocitywEditFieldLabel  matlab.ui.control.Label
        AngularvelocitywEditField       matlab.ui.control.EditField
        VoltageandcurrentequationsLabel  matlab.ui.control.Label
        vviexpTxvrexpTxLabel            matlab.ui.control.Label
        iiiexpTxirexpTxLabel            matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        ChoosethetypeoftransmissionlineDropDownLabel  matlab.ui.control.Label
        ChoosethetypeoftransmissionlineDropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = public)
         T=0% Description
         R=0
         L=0
         C=0
         V=0
         I=0
         Zo=0
         Zin=0
         ZL=0
         Rc=0
         S=0
         w=0
         G=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Transmission_coeffEditField_2.Value="Calculating.."
            app.R=app.ResistanceREditField.Value
            app.L=app.InductanceLEditField.Value
            app.C=app.CapacitanceCEditField.Value
            app.w=app.angular_velocitywEditField.Value
            app.G=app.ConductanceGEditField.Value
            app.T=transmission_coeff(app.R,app.L,app.C,app.w,app.G)
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*i 
            end
            app.Transmission_coeffEditField_2.Value=string(app.T)
        end

        % Button pushed function: CalculateButton_9
        function CalculateButton_9Pushed(app, event)
            vr=app.ReflectedvoltageVrEditField_3.Value
            vi=app.IncidentvoltageViEditField_3.Value
            x=-abs(app.DistancexEditField_2.Value)
            app.T=eval(app.Transmission_coeffEditField_5.Value)
            %app.Transmission_coeffEditField_2.Value=app.ChoosethetypeoftransmissionlineDropDown.Value
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*i 
            end
            vr=vr*exp(app.T*x)
            vi=vi*exp(-app.T*x)
            app.Rc=reflection_coeff(vr,vi,1,0,0,0)
            app.Reflection_coefficientEditField_5.Value=string(app.Rc)
        end

        % Button pushed function: CalculateButton_10
        function CalculateButton_10Pushed(app, event)
            ir=app.ReflectedCurrentIrEditField.Value
            ii=app.IncidentCurrentIiEditField.Value
            x=-abs(app.DistancexEditField_3.Value)
            app.T=eval(app.Transmission_coeffEditField_6.Value)
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*i 
            end
            ir=ir*exp(app.T*x)
            ii=ii*exp(-app.T*x)
            app.Rc=reflection_coeff(ir,ii,2,0,0,0)
            app.Reflection_coefficientEditField_6.Value=string(app.Rc)
        end

        % Button pushed function: CalculateButton_7
        function CalculateButton_7Pushed(app, event)
            app.ZL=eval(app.LoadImpedanceZlEditField_3.Value)
            app.Zo=eval(app.CharacteristicImpedanceZ0EditField_3.Value)
            app.Rc=reflection_coeff(0,0,3,app.ZL,app.Zo,0)
            app.Reflection_coefficientEditField_3.Value=string(app.Rc)
        end

        % Button pushed function: CalculateButton_8
        function CalculateButton_8Pushed(app, event)
            vr=app.ReflectedvoltageVrEditField_2.Value
            vi=app.IncidentvoltageViEditField_2.Value
            x=-abs(app.DistancexEditField.Value)
            app.T=eval(app.Transmission_coeffEditField_4.Value)
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*i 
            end
            app.V=voltage(app.T,vi,vr,x)
            app.VoltageVEditField.Value=string(app.V)
        end

        % Button pushed function: CalculateButton_11
        function CalculateButton_11Pushed(app, event)
            ir=app.ReflectedcurrentIrEditField.Value
            ii=app.IncidentcurrentIiEditField.Value
            x=-abs(app.DistancexEditField_4.Value)
            app.T=eval(app.Transmission_coeffEditField_7.Value)
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*i 
            end
            app.I=current(app.T,ii,ir,x)
            app.CurrentIEditField.Value=string(app.I)
        end

        % Button pushed function: CalculateButton_25
        function CalculateButton_25Pushed(app, event)
            Vmax=app.VmaxxEditField.Value
            Vmin=app.VminxEditField.Value
            app.S=standing_wave_ratio(Vmax,Vmin,1,0)
            app.StandingWaveRatioSEditField.Value=string(app.S)
        end

        % Button pushed function: CalculateButton_26
        function CalculateButton_26Pushed(app, event)
            app.Rc=app.ReflectionCoefficientREditField_3.Value
            app.S=standing_wave_ratio(0,0,2,app.Rc)
            app.StandingWaveRatioSEditField_2.Value=string(app.S)
            
        end

        % Button pushed function: CalculateButton_12
        function CalculateButton_12Pushed(app, event)
            v=app.VoltageVEditField_2.Value
            io=app.CurrentIEditField_2.Value
            if app.ViIiButton.Value
                app.Zo=characteristic_imp(v,io,1,0,0,0,0,0)
            end
            if app.VrIrButton.Value
                app.Zo=characteristic_imp(v,io,2,0,0,0,0,0)
            end
            app.CharactersticImpZ0EditField_8.Value=string(app.Zo)
        end

        % Button pushed function: CalculateButton_13
        function CalculateButton_13Pushed(app, event)
            app.R=app.ResistanceREditField_2.Value
            app.L=app.InductanceLEditField_2.Value
            app.C=app.CapacitanceCEditField_2.Value
            app.G=app.ConductanceGEditField_2.Value
            app.w=app.angular_velocitywEditField_2.Value
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.G=0
               app.R=0
            end
            app.Zo=sqrt((app.R+(app.w*app.L)*i)/(app.G+(app.w*app.C)*i))
            app.CharactersticImpZ0EditField_2.Value=string(app.Zo)
        end

        % Button pushed function: CalculateButton_14
        function CalculateButton_14Pushed(app, event)
            app.ZL=eval(app.LoadImpedanceZLEditField_3.Value)
            app.Rc=app.ReflectionCoefficientREditField.Value
            app.Zo=characteristic_imp(app.ZL,0,4,app.Rc,0,0,0,0)
            app.CharactersticImpZ0EditField_7.Value=string(app.Zo)
        end

        % Button pushed function: CalculateButton_17
        function CalculateButton_17Pushed(app, event)
            vi=app.ViEditField.Value
            vr=app.VrEditField.Value
            ii=app.IiEditField.Value
            ir=app.IrEditField.Value
            l=app.lEditField_2.Value
            app.T=app.Transmission_coeffEditField_8.Value
            app.T=eval(app.T)
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*(0+1i) 
            end
            if string(app.Z0EditField.Value)~='None'
                app.Zo=app.Z0EditField.Value
                app.Zo=eval(app.Zo)
                app.V=app.Zo*current(app.T,ii,-ir,-l)
            else
                app.V=voltage(app.T,vi,vr,-l)
                
            end
            app.I=current(app.T,ii,ir,-l)
            app.Zin=impedance(app.V,app.I,1,0,0,0,0)
            app.InputImpedancceZinEditField.Value=string(app.Zin)
        end

        % Button pushed function: CalculateButton_23
        function CalculateButton_23Pushed(app, event)
            vi=app.ViEditField_2.Value
            vr=app.VrEditField_2.Value
            ii=app.IiEditField_2.Value
            ir=app.IrEditField_2.Value
            if string(app.Z0EditField_2.Value)~='None'
                app.Zo=eval(app.Z0EditField_2.Value)
                app.V=app.Zo*current(0,ii,-ir,0)
            else
                app.V=voltage(0,vi,vr,0)
                
            end
            app.I=current(0,ii,ir,0)
            app.ZL=impedance(app.V,app.I,1,0,0,0,0)
            app.LoadImpedancceZLEditField.Value=string(app.ZL)
        end

        % Button pushed function: CalculateButton_22
        function CalculateButton_22Pushed(app, event)
            app.Zo=eval(app.CharacteristicImpedanceZoEditField.Value)
            app.Rc=app.ReflectionCoefficientREditField_2.Value
            app.ZL=impedance(0,0,4,0,app.Zo,app.Rc,0)
            app.LoadImpedanceZLEditField_4.Value=string(app.ZL)
        end

        % Button pushed function: CalculateButton_19
        function CalculateButton_19Pushed(app, event)
            app.ZL=eval(app.ZLEditField.Value)
            app.Zo=eval(app.ZoEditField.Value)
            app.T=eval(app.EditField.Value)
            cond=str2num(app.DropDown_2.Value)
            l=abs(app.lEditField.Value)
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*i
            end
            app.Zin=impedance(0,0,cond,app.ZL,app.Zo,app.T,l)
            app.InputImpedanceZinEditField_3.Value=string(app.Zin)
        end

        % Button pushed function: CalculateButton_27
        function CalculateButton_27Pushed(app, event)
            wl=app.WavelengthEditField.Value
            x=abs(app.EditField_3.Value)
            phi=0
            app.T=eval(app.TransmissionCoeffTEditField.Value)
            if app.T==0
                app.T=2*pi*i/wl
            elseif app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
                             app.T=imag(app.T)*i
                
                
            end
            while x-wl/2>=0
                x=x-wl/2
            end
            if app.DropDown_3.Value=='1'
                phi=4*pi*x/wl-pi
            else
                phi=4*pi*x/wl
            end
            app.S=app.StandingWaveRatioSEditField_3.Value
            R=(app.S-1)/(app.S+1)
            app.Rc=R*exp(phi*i)
            app.Zo=eval(app.CharacteristicImpedanceZoEditField_2.Value)
            app.ZL=app.Zo*(app.Rc+1)/(1-app.Rc)
            x1=-abs(app.DistancefromloadxEditField.Value)
            Rx=app.Rc*exp(2*app.T*(-x1))
            app.ReflectioncoefficientRx0EditField.Value=string(app.Rc)
            app.ReflectioncoefficientRxEditField.Value=string(Rx)
            app.LoadResistanceZLEditField.Value=string(app.ZL)
            conclusion=''
            if abs(app.ZL)==inf
                conclusion='Open circuit load'
            elseif abs(app.ZL)>abs(app.Zo)||abs(app.ZL)==abs(app.Zo)
                conclusion='Resistive Load'
            elseif abs(app.ZL)==0
                conclusion='short circuit load'
                
                
            end
            app.ConclusionEditField.Value=conclusion
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            incident=app.ViorIiEditField.Value
            reflected=app.VrorIrEditField.Value
            app.T=eval(app.TransmissionCoefficientTEditField.Value)
            if app.ChoosethetypeoftransmissionlineDropDown.Value=='1'
               app.T=imag(app.T)*i 
            end
            l=-abs(app.DistanceofsourcefromloadxEditField.Value)
            x=l:0.01:0;
            y1=incident*exp(-app.T*x);
            y2=reflected*exp(app.T*x);
            plot(app.UIAxes2_2,x,(y1));
            plot(app.UIAxes2,x,(y2));
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.ResistanceREditField_3.Value=string(app.R)
            app.CapacitanceCEditField_3.Value=string(app.C)
            app.InductanceLEditField_3.Value=string(app.L)
            app.CharacteristicImpZoEditField.Value=string(app.Zo)
            app.InputImpedanceZinEditField_4.Value=string(app.Zin)
            app.LoadImpedanceZLEditField_5.Value=string(app.ZL)
            app.ConductanceGEditField_3.Value=string(app.G)
            app.StandingWaveRatioSEditField_4.Value=string(app.S)
            app.ReflectionCoefficientREditField_4.Value=string(app.Rc)
            app.VoltageVEditField_3.Value=string(app.V)
            app.CurrentIEditField_3.Value=string(app.I)
            app.TransmissionCoefficientTEditField_2.Value=string(app.T)
            app.AngularvelocitywEditField.Value=string(app.w)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CoefficientsTab = uitab(app.TabGroup);
            app.CoefficientsTab.Title = 'Coefficients';
            app.TransmissionCoefficientPanel = uipanel(app.CoefficientsTab);
            app.TransmissionCoefficientPanel.Title = 'Transmission Coefficient';
            app.ResistanceREditFieldLabel = uilabel(app.TransmissionCoefficientPanel);
            app.ResistanceREditFieldLabel.Text = 'Resistance (R)';
            app.ResistanceREditField = uieditfield(app.TransmissionCoefficientPanel, 'numeric');
            app.InductanceLEditFieldLabel = uilabel(app.TransmissionCoefficientPanel);
            app.InductanceLEditFieldLabel.Text = 'Inductance (L)';
            app.InductanceLEditField = uieditfield(app.TransmissionCoefficientPanel, 'numeric');
            app.CapacitanceCEditFieldLabel = uilabel(app.TransmissionCoefficientPanel);
            app.CapacitanceCEditFieldLabel.Text = 'Capacitance (C)';
            app.CapacitanceCEditField = uieditfield(app.TransmissionCoefficientPanel, 'numeric');
            app.angular_velocitywEditFieldLabel = uilabel(app.TransmissionCoefficientPanel);
            app.angular_velocitywEditFieldLabel.Text = 'angular_velocity (w)';
            app.angular_velocitywEditField = uieditfield(app.TransmissionCoefficientPanel, 'numeric');
            app.ConductanceGEditFieldLabel = uilabel(app.TransmissionCoefficientPanel);
            app.ConductanceGEditFieldLabel.Text = 'Conductance(G)';
            app.ConductanceGEditField = uieditfield(app.TransmissionCoefficientPanel, 'numeric');
            app.CalculateButton = uibutton(app.TransmissionCoefficientPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Transmission_coeffEditField_2Label = uilabel(app.TransmissionCoefficientPanel);
            app.Transmission_coeffEditField_2Label.Text = 'Transmission_coeff';
            app.Transmission_coeffEditField_2 = uieditfield(app.TransmissionCoefficientPanel, 'text');
            app.ReflectionCoefficientPanel = uipanel(app.CoefficientsTab);
            app.ReflectionCoefficientPanel.Title = 'Reflection Coefficient';
            app.TabGroup2 = uitabgroup(app.ReflectionCoefficientPanel);
            app.VrViTab = uitab(app.TabGroup2);
            app.VrViTab.Title = 'Vr / Vi';
            app.Panel_2 = uipanel(app.VrViTab);
            app.CalculateButton_9 = uibutton(app.Panel_2, 'push');
            app.CalculateButton_9.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_9Pushed, true);
            app.CalculateButton_9.Text = 'Calculate';
            app.ReflectedvoltageVrEditField_3Label = uilabel(app.Panel_2);
            app.ReflectedvoltageVrEditField_3Label.Text = 'Reflected voltage(Vr)';
            app.ReflectedvoltageVrEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.IncidentvoltageViEditField_3Label = uilabel(app.Panel_2);
            app.IncidentvoltageViEditField_3Label.Text = 'Incident voltage(Vi)';
            app.IncidentvoltageViEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.Transmission_coeffEditField_5Label = uilabel(app.Panel_2);
            app.Transmission_coeffEditField_5Label.Text = 'Transmission_coeff';
            app.Transmission_coeffEditField_5 = uieditfield(app.Panel_2, 'text');
            app.Reflection_coefficientEditField_5Label = uilabel(app.Panel_2);
            app.Reflection_coefficientEditField_5Label.Text = 'Reflection_coefficient';
            app.Reflection_coefficientEditField_5 = uieditfield(app.Panel_2, 'text');
            app.DistancexEditField_2Label = uilabel(app.Panel_2);
            app.DistancexEditField_2Label.Text = 'Distance (x)';
            app.DistancexEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.IrIiTab = uitab(app.TabGroup2);
            app.IrIiTab.Title = '- Ir / Ii';
            app.Panel_3 = uipanel(app.IrIiTab);
            app.CalculateButton_10 = uibutton(app.Panel_3, 'push');
            app.CalculateButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_10Pushed, true);
            app.CalculateButton_10.Text = 'Calculate';
            app.ReflectedCurrentIrEditFieldLabel = uilabel(app.Panel_3);
            app.ReflectedCurrentIrEditFieldLabel.Text = 'Reflected Current (Ir)';
            app.ReflectedCurrentIrEditField = uieditfield(app.Panel_3, 'numeric');
            app.IncidentCurrentIiEditFieldLabel = uilabel(app.Panel_3);
            app.IncidentCurrentIiEditFieldLabel.Text = 'Incident Current (Ii)';
            app.IncidentCurrentIiEditField = uieditfield(app.Panel_3, 'numeric');
            app.Transmission_coeffEditField_6Label = uilabel(app.Panel_3);
            app.Transmission_coeffEditField_6Label.Text = 'Transmission_coeff';
            app.Transmission_coeffEditField_6 = uieditfield(app.Panel_3, 'text');
            app.Reflection_coefficientEditField_6Label = uilabel(app.Panel_3);
            app.Reflection_coefficientEditField_6Label.Text = 'Reflection_coefficient';
            app.Reflection_coefficientEditField_6 = uieditfield(app.Panel_3, 'text');
            app.DistancexEditField_3Label = uilabel(app.Panel_3);
            app.DistancexEditField_3Label.Text = 'Distance (x)';
            app.DistancexEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.usingimpedancex0Tab = uitab(app.TabGroup2);
            app.usingimpedancex0Tab.Title = 'using impedance(x=0)';
            app.LoadImpedanceZlEditField_3Label = uilabel(app.usingimpedancex0Tab);
            app.LoadImpedanceZlEditField_3Label.Text = 'Load Impedance (Zl)';
            app.LoadImpedanceZlEditField_3 = uieditfield(app.usingimpedancex0Tab, 'text');
            app.CharacteristicImpedanceZ0EditField_3Label = uilabel(app.usingimpedancex0Tab);
            app.CharacteristicImpedanceZ0EditField_3Label.Text = 'Characteristic Impedance(Z0)';
            app.CharacteristicImpedanceZ0EditField_3 = uieditfield(app.usingimpedancex0Tab, 'text');
            app.CalculateButton_7 = uibutton(app.usingimpedancex0Tab, 'push');
            app.CalculateButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_7Pushed, true);
            app.CalculateButton_7.Text = 'Calculate';
            app.Reflection_coefficientEditField_3Label = uilabel(app.usingimpedancex0Tab);
            app.Reflection_coefficientEditField_3Label.Text = 'Reflection_coefficient';
            app.Reflection_coefficientEditField_3 = uieditfield(app.usingimpedancex0Tab, 'text');
            app.SelecttheformulayouwanttouseLabel = uilabel(app.ReflectionCoefficientPanel);
            app.SelecttheformulayouwanttouseLabel.Text = 'Select the formula you want to use';
            app.VoltageCurrentTab = uitab(app.TabGroup);
            app.VoltageCurrentTab.Title = 'Voltage&Current';
            app.VoltagePanel = uipanel(app.VoltageCurrentTab);
            app.VoltagePanel.Title = 'Voltage';
            app.Panel = uipanel(app.VoltagePanel);
            app.CalculateButton_8 = uibutton(app.Panel, 'push');
            app.CalculateButton_8.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_8Pushed, true);
            app.CalculateButton_8.Text = 'Calculate';
            app.ReflectedvoltageVrEditField_2Label = uilabel(app.Panel);
            app.ReflectedvoltageVrEditField_2Label.Text = 'Reflected voltage(Vr)';
            app.ReflectedvoltageVrEditField_2 = uieditfield(app.Panel, 'numeric');
            app.IncidentvoltageViEditField_2Label = uilabel(app.Panel);
            app.IncidentvoltageViEditField_2Label.Text = 'Incident voltage(Vi)';
            app.IncidentvoltageViEditField_2 = uieditfield(app.Panel, 'numeric');
            app.Transmission_coeffEditField_4Label = uilabel(app.Panel);
            app.Transmission_coeffEditField_4Label.Text = 'Transmission_coeff';
            app.Transmission_coeffEditField_4 = uieditfield(app.Panel, 'text');
            app.VoltageVEditFieldLabel = uilabel(app.Panel);
            app.VoltageVEditFieldLabel.Text = 'Voltage (V)';
            app.VoltageVEditField = uieditfield(app.Panel, 'text');
            app.DistancexEditFieldLabel = uilabel(app.Panel);
            app.DistancexEditFieldLabel.Text = 'Distance (x)';
            app.DistancexEditField = uieditfield(app.Panel, 'numeric');
            app.CurrentPanel = uipanel(app.VoltageCurrentTab);
            app.CurrentPanel.Title = 'Current';
            app.Panel_4 = uipanel(app.CurrentPanel);
            app.CalculateButton_11 = uibutton(app.Panel_4, 'push');
            app.CalculateButton_11.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_11Pushed, true);
            app.CalculateButton_11.Text = 'Calculate';
            app.ReflectedcurrentIrLabel = uilabel(app.Panel_4);
            app.ReflectedcurrentIrLabel.Text = 'Reflected current (Ir)';
            app.ReflectedcurrentIrEditField = uieditfield(app.Panel_4, 'numeric');
            app.IncidentcurrentIiLabel = uilabel(app.Panel_4);
            app.IncidentcurrentIiLabel.Text = 'Incident current (Ii)';
            app.IncidentcurrentIiEditField = uieditfield(app.Panel_4, 'numeric');
            app.Transmission_coeffEditField_7Label = uilabel(app.Panel_4);
            app.Transmission_coeffEditField_7Label.Text = 'Transmission_coeff';
            app.Transmission_coeffEditField_7 = uieditfield(app.Panel_4, 'text');
            app.CurrentIEditFieldLabel = uilabel(app.Panel_4);
            app.CurrentIEditFieldLabel.Text = 'Current (I)';
            app.CurrentIEditField = uieditfield(app.Panel_4, 'text');
            app.DistancexEditField_4Label = uilabel(app.Panel_4);
            app.DistancexEditField_4Label.Text = 'Distance (x)';
            app.DistancexEditField_4 = uieditfield(app.Panel_4, 'numeric');
            app.ImpedancesTab = uitab(app.TabGroup);
            app.ImpedancesTab.Title = 'Impedances';
            app.CharacteristicImpedancePanel = uipanel(app.ImpedancesTab);
            app.CharacteristicImpedancePanel.Title = 'Characteristic Impedance';
            app.TabGroup3 = uitabgroup(app.CharacteristicImpedancePanel);
            app.VITab = uitab(app.TabGroup3);
            app.VITab.Title = 'V / I';
            app.VoltageVEditField_2Label = uilabel(app.VITab);
            app.VoltageVEditField_2Label.Text = 'Voltage (V)';
            app.VoltageVEditField_2 = uieditfield(app.VITab, 'numeric');
            app.CurrentIEditField_2Label = uilabel(app.VITab);
            app.CurrentIEditField_2Label.Text = 'Current (I)';
            app.CurrentIEditField_2 = uieditfield(app.VITab, 'numeric');
            app.ButtonGroup = uibuttongroup(app.VITab);
            app.ViIiButton = uiradiobutton(app.ButtonGroup);
            app.ViIiButton.Text = ' Vi/Ii';
            app.ViIiButton.Value = true;
            app.VrIrButton = uiradiobutton(app.ButtonGroup);
            app.VrIrButton.Text = '-Vr/Ir';
            app.ChoosetheformulaLabel = uilabel(app.VITab);
            app.ChoosetheformulaLabel.Text = 'Choose the formula';
            app.CalculateButton_12 = uibutton(app.VITab, 'push');
            app.CalculateButton_12.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_12Pushed, true);
            app.CalculateButton_12.Text = 'Calculate';
            app.CharactersticImpZ0EditField_8Label = uilabel(app.VITab);
            app.CharactersticImpZ0EditField_8Label.Text = 'Characterstic Imp (Z0)';
            app.CharactersticImpZ0EditField_8 = uieditfield(app.VITab, 'text');
            app.sqrootRjwLGjwCTab = uitab(app.TabGroup3);
            app.sqrootRjwLGjwCTab.Title = 'sq-root{(R+jwL)/(G+jwC)}';
            app.Panel_5 = uipanel(app.sqrootRjwLGjwCTab);
            app.ResistanceREditField_2Label = uilabel(app.Panel_5);
            app.ResistanceREditField_2Label.Text = 'Resistance (R)';
            app.ResistanceREditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.InductanceLEditField_2Label = uilabel(app.Panel_5);
            app.InductanceLEditField_2Label.Text = 'Inductance (L)';
            app.InductanceLEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.angular_velocitywEditField_2Label = uilabel(app.Panel_5);
            app.angular_velocitywEditField_2Label.Text = 'angular_velocity (w)';
            app.angular_velocitywEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.ConductanceGEditField_2Label = uilabel(app.Panel_5);
            app.ConductanceGEditField_2Label.Text = 'Conductance(G)';
            app.ConductanceGEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.CharactersticImpZ0EditField_2Label = uilabel(app.Panel_5);
            app.CharactersticImpZ0EditField_2Label.Text = 'Characterstic Imp (Z0)';
            app.CharactersticImpZ0EditField_2 = uieditfield(app.Panel_5, 'text');
            app.CapacitanceCEditField_2Label = uilabel(app.Panel_5);
            app.CapacitanceCEditField_2Label.Text = 'Capacitance (C)';
            app.CapacitanceCEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.CalculateButton_13 = uibutton(app.Panel_5, 'push');
            app.CalculateButton_13.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_13Pushed, true);
            app.CalculateButton_13.Text = 'Calculate';
            app.ZL1R1RTab = uitab(app.TabGroup3);
            app.ZL1R1RTab.Title = 'ZL(1-R)/(1+R)';
            app.ReflectionCoefficientREditFieldLabel = uilabel(app.ZL1R1RTab);
            app.ReflectionCoefficientREditFieldLabel.Text = 'Reflection Coefficient (R)';
            app.ReflectionCoefficientREditField = uieditfield(app.ZL1R1RTab, 'numeric');
            app.CalculateButton_14 = uibutton(app.ZL1R1RTab, 'push');
            app.CalculateButton_14.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_14Pushed, true);
            app.CalculateButton_14.Text = 'Calculate';
            app.CharactersticImpZ0EditField_7Label = uilabel(app.ZL1R1RTab);
            app.CharactersticImpZ0EditField_7Label.Text = 'Characterstic Imp (Z0)';
            app.CharactersticImpZ0EditField_7 = uieditfield(app.ZL1R1RTab, 'text');
            app.LoadImpedanceZLEditField_3Label = uilabel(app.ZL1R1RTab);
            app.LoadImpedanceZLEditField_3Label.Text = 'Load Impedance (ZL)';
            app.LoadImpedanceZLEditField_3 = uieditfield(app.ZL1R1RTab, 'text');
            app.ChoosetherequiredmethodLabel = uilabel(app.CharacteristicImpedancePanel);
            app.ChoosetherequiredmethodLabel.Text = 'Choose the required method';
            app.InputImpedancePanel = uipanel(app.ImpedancesTab);
            app.InputImpedancePanel.Title = 'Input Impedance';
            app.TabGroup3_2 = uitabgroup(app.InputImpedancePanel);
            app.VsIsTab = uitab(app.TabGroup3_2);
            app.VsIsTab.Title = 'Vs / Is';
            app.CalculateButton_17 = uibutton(app.VsIsTab, 'push');
            app.CalculateButton_17.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_17Pushed, true);
            app.CalculateButton_17.Text = 'Calculate';
            app.ViEditFieldLabel = uilabel(app.VsIsTab);
            app.ViEditFieldLabel.Text = 'Vi';
            app.ViEditField = uieditfield(app.VsIsTab, 'numeric');
            app.VrEditFieldLabel = uilabel(app.VsIsTab);
            app.VrEditFieldLabel.Text = 'Vr';
            app.VrEditField = uieditfield(app.VsIsTab, 'numeric');
            app.IiEditFieldLabel = uilabel(app.VsIsTab);
            app.IiEditFieldLabel.Text = 'Ii';
            app.IiEditField = uieditfield(app.VsIsTab, 'numeric');
            app.IrEditFieldLabel = uilabel(app.VsIsTab);
            app.IrEditFieldLabel.Text = 'Ir';
            app.IrEditField = uieditfield(app.VsIsTab, 'numeric');
            app.Z0EditFieldLabel = uilabel(app.VsIsTab);
            app.Z0EditFieldLabel.Text = 'Z0';
            app.Z0EditField = uieditfield(app.VsIsTab, 'text');
            app.Z0EditField.Value = 'None';
            app.InputImpedancceZinEditFieldLabel = uilabel(app.VsIsTab);
            app.InputImpedancceZinEditFieldLabel.Text = 'Input Impedancce (Zin)';
            app.InputImpedancceZinEditField = uieditfield(app.VsIsTab, 'text');
            app.NoteprovideZovalueofyoudonthavevoltagevaluesLabel = uilabel(app.VsIsTab);
            app.NoteprovideZovalueofyoudonthavevoltagevaluesLabel.Text = 'Note: provide Zo value of you dont have voltage values';
            app.lEditField_2Label = uilabel(app.VsIsTab);
            app.lEditField_2Label.Text = 'l';
            app.lEditField_2 = uieditfield(app.VsIsTab, 'numeric');
            app.Transmission_coeffEditField_8Label = uilabel(app.VsIsTab);
            app.Transmission_coeffEditField_8Label.Text = 'Transmission_coeff';
            app.Transmission_coeffEditField_8 = uieditfield(app.VsIsTab, 'text');
            app.ZoZlZotanhTlZoZLtanhTlTab = uitab(app.TabGroup3_2);
            app.ZoZlZotanhTlZoZLtanhTlTab.Title = 'Zo{(Zl+Zotanh(Tl))/(Zo+ZLtanh(Tl))';
            app.CalculateButton_19 = uibutton(app.ZoZlZotanhTlZoZLtanhTlTab, 'push');
            app.CalculateButton_19.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_19Pushed, true);
            app.CalculateButton_19.Text = 'Calculate';
            app.ZLEditFieldLabel = uilabel(app.ZoZlZotanhTlZoZLtanhTlTab);
            app.ZLEditFieldLabel.Text = 'ZL';
            app.ZLEditField = uieditfield(app.ZoZlZotanhTlZoZLtanhTlTab, 'text');
            app.ZoEditFieldLabel = uilabel(app.ZoZlZotanhTlZoZLtanhTlTab);
            app.ZoEditFieldLabel.Text = 'Zo';
            app.ZoEditField = uieditfield(app.ZoZlZotanhTlZoZLtanhTlTab, 'text');
            app.DropDown = uidropdown(app.ZoZlZotanhTlZoZLtanhTlTab);
            app.DropDown.Items = {'Transmission Coefficient', 'Wavelength', ''};
            app.DropDown.ItemsData = {'2', '3', '', ''};
            app.DropDown.Value = '2';
            app.EditField = uieditfield(app.ZoZlZotanhTlZoZLtanhTlTab, 'text');
            app.lEditFieldLabel = uilabel(app.ZoZlZotanhTlZoZLtanhTlTab);
            app.lEditFieldLabel.Text = 'l';
            app.lEditField = uieditfield(app.ZoZlZotanhTlZoZLtanhTlTab, 'numeric');
            app.InputImpedanceZinEditField_3Label = uilabel(app.ZoZlZotanhTlZoZLtanhTlTab);
            app.InputImpedanceZinEditField_3Label.Text = 'Input Impedance (Zin))';
            app.InputImpedanceZinEditField_3 = uieditfield(app.ZoZlZotanhTlZoZLtanhTlTab, 'text');
            app.ChoosetherequiredmethodLabel_2 = uilabel(app.InputImpedancePanel);
            app.ChoosetherequiredmethodLabel_2.Text = 'Choose the required method';
            app.Label = uilabel(app.InputImpedancePanel);
            app.Label.Text = '';
            app.LoadImpedancePanel = uipanel(app.ImpedancesTab);
            app.LoadImpedancePanel.Title = 'Load Impedance';
            app.TabGroup3_3 = uitabgroup(app.LoadImpedancePanel);
            app.VLILTab = uitab(app.TabGroup3_3);
            app.VLILTab.Title = 'VL / IL';
            app.CalculateButton_23 = uibutton(app.VLILTab, 'push');
            app.CalculateButton_23.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_23Pushed, true);
            app.CalculateButton_23.Text = 'Calculate';
            app.ViEditField_2Label = uilabel(app.VLILTab);
            app.ViEditField_2Label.Text = 'Vi';
            app.ViEditField_2 = uieditfield(app.VLILTab, 'numeric');
            app.VrEditField_2Label = uilabel(app.VLILTab);
            app.VrEditField_2Label.Text = 'Vr';
            app.VrEditField_2 = uieditfield(app.VLILTab, 'numeric');
            app.IiEditField_2Label = uilabel(app.VLILTab);
            app.IiEditField_2Label.Text = 'Ii';
            app.IiEditField_2 = uieditfield(app.VLILTab, 'numeric');
            app.IrEditField_2Label = uilabel(app.VLILTab);
            app.IrEditField_2Label.Text = 'Ir';
            app.IrEditField_2 = uieditfield(app.VLILTab, 'numeric');
            app.Z0EditField_2Label = uilabel(app.VLILTab);
            app.Z0EditField_2Label.Text = 'Z0';
            app.Z0EditField_2 = uieditfield(app.VLILTab, 'text');
            app.Z0EditField_2.Value = 'None';
            app.LoadImpedancceZLEditFieldLabel = uilabel(app.VLILTab);
            app.LoadImpedancceZLEditFieldLabel.Text = 'Load Impedancce (ZL)';
            app.LoadImpedancceZLEditField = uieditfield(app.VLILTab, 'text');
            app.NoteprovideZovalueofyoudonthavevoltagevaluesLabel_2 = uilabel(app.VLILTab);
            app.NoteprovideZovalueofyoudonthavevoltagevaluesLabel_2.Text = 'Note: provide Zo value of you dont have voltage values';
            app.Zo1R1RTab = uitab(app.TabGroup3_3);
            app.Zo1R1RTab.Title = 'Zo(1+R)/(1-R)';
            app.ReflectionCoefficientREditField_2Label = uilabel(app.Zo1R1RTab);
            app.ReflectionCoefficientREditField_2Label.Text = 'Reflection Coefficient (R)';
            app.ReflectionCoefficientREditField_2 = uieditfield(app.Zo1R1RTab, 'numeric');
            app.CalculateButton_22 = uibutton(app.Zo1R1RTab, 'push');
            app.CalculateButton_22.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_22Pushed, true);
            app.CalculateButton_22.Text = 'Calculate';
            app.LoadImpedanceZLEditField_4Label = uilabel(app.Zo1R1RTab);
            app.LoadImpedanceZLEditField_4Label.Text = 'Load Impedance (ZL)';
            app.LoadImpedanceZLEditField_4 = uieditfield(app.Zo1R1RTab, 'text');
            app.CharacteristicImpedanceZoEditFieldLabel = uilabel(app.Zo1R1RTab);
            app.CharacteristicImpedanceZoEditFieldLabel.Text = 'Characteristic Impedance (Zo)';
            app.CharacteristicImpedanceZoEditField = uieditfield(app.Zo1R1RTab, 'text');
            app.StandingWaveRatioTab = uitab(app.TabGroup);
            app.StandingWaveRatioTab.Title = 'StandingWaveRatio';
            app.VmaxxVminxPanel = uipanel(app.StandingWaveRatioTab);
            app.VmaxxVminxPanel.Title = 'Vmax(x)/Vmin(x)';
            app.Panel_6 = uipanel(app.VmaxxVminxPanel);
            app.CalculateButton_25 = uibutton(app.Panel_6, 'push');
            app.CalculateButton_25.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_25Pushed, true);
            app.CalculateButton_25.Text = 'Calculate';
            app.VmaxxEditFieldLabel = uilabel(app.Panel_6);
            app.VmaxxEditFieldLabel.Text = 'Vmax(x)';
            app.VmaxxEditField = uieditfield(app.Panel_6, 'numeric');
            app.VminxEditFieldLabel = uilabel(app.Panel_6);
            app.VminxEditFieldLabel.Text = 'Vmin(x)';
            app.VminxEditField = uieditfield(app.Panel_6, 'numeric');
            app.StandingWaveRatioSEditFieldLabel = uilabel(app.Panel_6);
            app.StandingWaveRatioSEditFieldLabel.Text = 'Standing Wave Ratio (S)';
            app.StandingWaveRatioSEditField = uieditfield(app.Panel_6, 'text');
            app.R1RPanel = uipanel(app.StandingWaveRatioTab);
            app.R1RPanel.Title = '(1+|R|)/(1-|R|)';
            app.Panel_7 = uipanel(app.R1RPanel);
            app.CalculateButton_26 = uibutton(app.Panel_7, 'push');
            app.CalculateButton_26.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_26Pushed, true);
            app.CalculateButton_26.Text = 'Calculate';
            app.ReflectionCoefficientREditField_3Label = uilabel(app.Panel_7);
            app.ReflectionCoefficientREditField_3Label.Text = 'Reflection Coefficient (R)';
            app.ReflectionCoefficientREditField_3 = uieditfield(app.Panel_7, 'numeric');
            app.StandingWaveRatioSEditField_2Label = uilabel(app.Panel_7);
            app.StandingWaveRatioSEditField_2Label.Text = 'Standing Wave Ratio (S)';
            app.StandingWaveRatioSEditField_2 = uieditfield(app.Panel_7, 'text');
            app.NumericalTab = uitab(app.TabGroup);
            app.NumericalTab.Title = 'Numerical';
            app.Panel_8 = uipanel(app.NumericalTab);
            app.StandingWaveRatioSEditField_3Label = uilabel(app.Panel_8);
            app.StandingWaveRatioSEditField_3Label.Text = 'Standing Wave Ratio (S)';
            app.StandingWaveRatioSEditField_3 = uieditfield(app.Panel_8, 'numeric');
            app.DropDown_3 = uidropdown(app.Panel_8);
            app.DropDown_3.Items = {'Minima', 'Maxima'};
            app.DropDown_3.ItemsData = {'1', '2'};
            app.DropDown_3.Value = '1';
            app.EditField_3 = uieditfield(app.Panel_8, 'numeric');
            app.DistanceofLabel = uilabel(app.Panel_8);
            app.DistanceofLabel.Text = 'Distance of ';
            app.fromloadLabel = uilabel(app.Panel_8);
            app.fromloadLabel.Text = 'from load';
            app.WavelengthLabel = uilabel(app.Panel_8);
            app.WavelengthLabel.Text = 'Wavelength     ';
            app.WavelengthEditField = uieditfield(app.Panel_8, 'numeric');
            app.CharacteristicImpedanceZoEditField_2Label = uilabel(app.Panel_8);
            app.CharacteristicImpedanceZoEditField_2Label.Text = 'Characteristic Impedance (Zo)';
            app.CharacteristicImpedanceZoEditField_2 = uieditfield(app.Panel_8, 'text');
            app.TransmissionCoeffTEditFieldLabel = uilabel(app.Panel_8);
            app.TransmissionCoeffTEditFieldLabel.Text = 'Transmission Coeff (T)';
            app.TransmissionCoeffTEditField = uieditfield(app.Panel_8, 'text');
            app.TransmissionCoeffTEditField.Value = '0';
            app.DistancefromloadxEditFieldLabel = uilabel(app.Panel_8);
            app.DistancefromloadxEditFieldLabel.Text = 'Distance from load (x)';
            app.DistancefromloadxEditField = uieditfield(app.Panel_8, 'numeric');
            app.FillinthevaluesLabel = uilabel(app.NumericalTab);
            app.FillinthevaluesLabel.Text = 'Fill in the values';
            app.ResultsLabel = uilabel(app.NumericalTab);
            app.ResultsLabel.Text = 'Results';
            app.Panel_9 = uipanel(app.NumericalTab);
            app.ReflectioncoefficientRx0EditFieldLabel = uilabel(app.Panel_9);
            app.ReflectioncoefficientRx0EditFieldLabel.Text = 'Reflection coefficient R(x=0)';
            app.ReflectioncoefficientRx0EditField = uieditfield(app.Panel_9, 'text');
            app.LoadResistanceZLEditFieldLabel = uilabel(app.Panel_9);
            app.LoadResistanceZLEditFieldLabel.Text = 'Load Resistance (ZL)';
            app.LoadResistanceZLEditField = uieditfield(app.Panel_9, 'text');
            app.ReflectioncoefficientRxEditFieldLabel = uilabel(app.Panel_9);
            app.ReflectioncoefficientRxEditFieldLabel.Text = 'Reflection coefficient R(x)';
            app.ReflectioncoefficientRxEditField = uieditfield(app.Panel_9, 'text');
            app.ConclusionEditFieldLabel = uilabel(app.Panel_9);
            app.ConclusionEditFieldLabel.Text = 'Conclusion';
            app.ConclusionEditField = uieditfield(app.Panel_9, 'text');
            app.CalculateButton_27 = uibutton(app.NumericalTab, 'push');
            app.CalculateButton_27.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_27Pushed, true);
            app.CalculateButton_27.Text = 'Calculate';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes2 = uiaxes(app.PlotTab);
            title(app.UIAxes2, 'Reflected ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.PlotTab);
            title(app.UIAxes2_2, 'Incident')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.ViorIiEditFieldLabel = uilabel(app.PlotTab);
            app.ViorIiEditFieldLabel.Text = 'Vi or Ii';
            app.ViorIiEditField = uieditfield(app.PlotTab, 'numeric');
            app.VrorIrEditFieldLabel = uilabel(app.PlotTab);
            app.VrorIrEditFieldLabel.Text = 'Vr or Ir';
            app.VrorIrEditField = uieditfield(app.PlotTab, 'numeric');
            app.TransmissionCoefficientTEditFieldLabel = uilabel(app.PlotTab);
            app.TransmissionCoefficientTEditFieldLabel.Text = 'Transmission Coefficient (T)';
            app.TransmissionCoefficientTEditField = uieditfield(app.PlotTab, 'text');
            app.PlotButton = uibutton(app.PlotTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DistanceofsourcefromloadxEditFieldLabel = uilabel(app.PlotTab);
            app.DistanceofsourcefromloadxEditFieldLabel.Text = 'Distance of source from load (x)';
            app.DistanceofsourcefromloadxEditField = uieditfield(app.PlotTab, 'numeric');
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.Panel_10 = uipanel(app.HistoryTab);
            app.ResistanceREditField_3Label = uilabel(app.Panel_10);
            app.ResistanceREditField_3Label.Text = 'Resistance (R)';
            app.ResistanceREditField_3 = uieditfield(app.Panel_10, 'text');
            app.CapacitanceCEditField_3Label = uilabel(app.Panel_10);
            app.CapacitanceCEditField_3Label.Text = 'Capacitance (C)';
            app.CapacitanceCEditField_3 = uieditfield(app.Panel_10, 'text');
            app.InductanceLEditField_3Label = uilabel(app.Panel_10);
            app.InductanceLEditField_3Label.Text = 'Inductance (L)';
            app.InductanceLEditField_3 = uieditfield(app.Panel_10, 'text');
            app.CharacteristicImpZoEditFieldLabel = uilabel(app.Panel_10);
            app.CharacteristicImpZoEditFieldLabel.Text = 'Characteristic Imp (Zo)';
            app.CharacteristicImpZoEditField = uieditfield(app.Panel_10, 'text');
            app.InputImpedanceZinEditField_4Label = uilabel(app.Panel_10);
            app.InputImpedanceZinEditField_4Label.Text = 'Input Impedance (Zin)';
            app.InputImpedanceZinEditField_4 = uieditfield(app.Panel_10, 'text');
            app.LoadImpedanceZLEditField_5Label = uilabel(app.Panel_10);
            app.LoadImpedanceZLEditField_5Label.Text = 'Load Impedance (ZL)';
            app.LoadImpedanceZLEditField_5 = uieditfield(app.Panel_10, 'text');
            app.ConductanceGEditField_3Label = uilabel(app.Panel_10);
            app.ConductanceGEditField_3Label.Text = 'Conductance (G)';
            app.ConductanceGEditField_3 = uieditfield(app.Panel_10, 'text');
            app.StandingWaveRatioSEditField_4Label = uilabel(app.Panel_10);
            app.StandingWaveRatioSEditField_4Label.Text = 'Standing Wave Ratio (S)';
            app.StandingWaveRatioSEditField_4 = uieditfield(app.Panel_10, 'text');
            app.ReflectionCoefficientREditField_4Label = uilabel(app.Panel_10);
            app.ReflectionCoefficientREditField_4Label.Text = 'Reflection Coefficient (R)';
            app.ReflectionCoefficientREditField_4 = uieditfield(app.Panel_10, 'text');
            app.VoltageVEditField_3Label = uilabel(app.Panel_10);
            app.VoltageVEditField_3Label.Text = 'Voltage (V)';
            app.VoltageVEditField_3 = uieditfield(app.Panel_10, 'text');
            app.CurrentIEditField_3Label = uilabel(app.Panel_10);
            app.CurrentIEditField_3Label.Text = 'Current (I)';
            app.CurrentIEditField_3 = uieditfield(app.Panel_10, 'text');
            app.TransmissionCoefficientTEditField_2Label = uilabel(app.Panel_10);
            app.TransmissionCoefficientTEditField_2Label.Text = 'Transmission Coefficient (T)';
            app.TransmissionCoefficientTEditField_2 = uieditfield(app.Panel_10, 'text');
            app.AngularvelocitywEditFieldLabel = uilabel(app.Panel_10);
            app.AngularvelocitywEditFieldLabel.Text = 'Angular velocity (w)';
            app.AngularvelocitywEditField = uieditfield(app.Panel_10, 'text');
            app.VoltageandcurrentequationsLabel = uilabel(app.Panel_10);
            app.VoltageandcurrentequationsLabel.Text = 'Voltage and current equations:';
            app.vviexpTxvrexpTxLabel = uilabel(app.Panel_10);
            app.vviexpTxvrexpTxLabel.Text = 'v=vi*exp(-T*x)+vr*exp(T*x)';
            app.iiiexpTxirexpTxLabel = uilabel(app.Panel_10);
            app.iiiexpTxirexpTxLabel.Text = {'i=ii*exp(-T*x)+ir*exp(T*x)'; ''};
            app.RefreshButton = uibutton(app.HistoryTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh ';
            app.ChoosethetypeoftransmissionlineDropDownLabel = uilabel(app.UIFigure);
            app.ChoosethetypeoftransmissionlineDropDownLabel.Text = 'Choose the type of transmission line';
            app.ChoosethetypeoftransmissionlineDropDown = uidropdown(app.UIFigure);
            app.ChoosethetypeoftransmissionlineDropDown.Items = {'Lossless', 'Lossy'};
            app.ChoosethetypeoftransmissionlineDropDown.ItemsData = {'1', '2', ''};
            app.ChoosethetypeoftransmissionlineDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransmissionLinesCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end